classdef dfParseRawVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        rawVideoView       matlab.ui.control.UIAxes
        VideoFrameSpinner  matlab.ui.control.Spinner
        FrameSpinnerLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        appWinSize
        
        filePath % Description
        
        vidObj
        vidStr
        
        vidWidth
        vidHeight
        vidFrameR
        
        nFrames
        curFrame
    end

    methods (Access = private)
    
        function checkFile(app)
            if isfile(app.filePath)
                disp('Loading...')
                loadRawVideo(app)
            else
                error('Not a raw video file')
            end
            
        end
        
        function loadRawVideo(app)
            app.vidObj = VideoReader(app.filePath);
            
            app.vidWidth  = app.vidObj.Width;
            app.vidHeight = app.vidObj.Height;
            app.vidFrameR = app.vidObj.FrameRate;
            
            app.vidStr = struct('cdata', zeros(app.vidHeight, app.vidWidth, 3, 'uint8'), 'colormap', []);
            
            app.nFrames = 0;
            while hasFrame(app.vidObj)
                app.nFrames = app.nFrames+1;
                app.vidStr(app.nFrames).cdata = readFrame(app.vidObj);                
            end          
            
            app.VideoFrameSpinner.Value = 1;
            app.VideoFrameSpinner.Limits = [1 app.nFrames];
            app.FrameSpinnerLabel.Text = ['Frame # of ' num2str(app.nFrames)];
            
            setViewWindowParams(app)            
            dispFrame(app)
        end
        
        function dispFrame(app)
            image(app.rawVideoView, app.vidStr(app.VideoFrameSpinner.Value).cdata)
        end
        
        function setViewWindowParams(app)
            
            figW = app.vidWidth+10;
            figH = app.vidHeight+10;
            
            figX = 10;
            figY = app.appWinSize(2)-figH - 10;
            
            app.rawVideoView.Position = [figX figY figW figH];

            title(app.rawVideoView, []);
            xlabel(app.rawVideoView, []);
            ylabel(app.rawVideoView, []);
            app.rawVideoView.XAxis.TickLabels = {};
            app.rawVideoView.YAxis.TickLabels = {};
            
            app.rawVideoView.XLim = [0 app.vidWidth];
            app.rawVideoView.YLim = [0 app.vidHeight];
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function initParams(app)
            app.appWinSize = app.UIFigure.Position(3:4);
            
            app.filePath = fullfile('W:\Experiments', 'Dissociating-Role-of-Feedback-in-Voice-Motor-Control', 'DRF_ENP1', 'rawVideo', 'DRF_ENP1 rawVideo.avi');
            checkFile(app)
        end

        % Value changed function: VideoFrameSpinner
        function updateVideoView(app, event)
            dispFrame(app)           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.rawVideoView = uiaxes(app.UIFigure);
            app.rawVideoView.XTickLabel = {''; ''};
            app.rawVideoView.YTickLabel = '';
            app.VideoFrameSpinner = uispinner(app.UIFigure);
            app.VideoFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @updateVideoView, true);
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame # of 100';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfParseRawVideo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initParams)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end